classdef NewProbe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewProbeUIFigure     matlab.ui.Figure
        MeasLimitHEditField  matlab.ui.control.NumericEditField
        MeasLimitmLabel      matlab.ui.control.Label
        OkButton             matlab.ui.control.Button
        TypeDropDown         matlab.ui.control.DropDown
        TypeDropDownLabel    matlab.ui.control.Label
        AirButton            matlab.ui.control.Button
        ZeroButton           matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp % Description
        TempData             % Временная структура для хранения данных из Excel
        IsCalibrationDone = false; % Флаг: пройден ли весь путь (Zero + Air)

        % НОВЫЕ ПЕРЕМЕННЫЕ ДЛЯ БЭКАПА СТАРЫХ ДАННЫХ
        OriginalKzero
        OriginalAirGrad
        OriginalProbeType
        OriginalMeasLimitH
        OriginalAirSTD
        OriginalAirDelta
        OriginalWaveForm
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainAppRef)
            app.MainApp = MainAppRef;

            % СОЗДАЕМ БЭКАП:
            app.OriginalKzero   = app.MainApp.Settings.Kzero;
            app.OriginalAirGrad = app.MainApp.Settings.AirGrad;
            app.OriginalProbeType = app.MainApp.Settings.ProbeType;
            app.OriginalAirSTD = app.MainApp.Settings.AirSTD;
            app.OriginalAirDelta = app.MainApp.Settings.AirDelta;
            app.OriginalMeasLimitH = app.MainApp.Settings.MeasLimitH;
            app.OriginalWaveForm = app.MainApp.Settings.WaveForm;
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            app.MainApp.Settings.CmdZero = ~app.MainApp.Settings.CmdZero;

            app.ZeroButton.Enable = 'off';
            app.AirButton.Enable = 'on';
        end

        % Button pushed function: AirButton
        function AirButtonPushed(app, event)
            % Ваша исходная команда для железа
            app.MainApp.Settings.CmdAirGrad = ~app.MainApp.Settings.CmdAirGrad;

            % Поэтапная блокировка
            app.AirButton.Enable = 'off';

            % Включаем кнопку ОК — теперь пользователь может сохранить данные!
            app.OkButton.Enable = 'on';


        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            selectedType = app.TypeDropDown.Value;
            isValidType  = true;
            fileName     = '';

            app.IsCalibrationDone = false;
            app.ZeroButton.Enable = 'off';
            app.AirButton.Enable = 'off';

            switch selectedType
                case 0, fileName = 'calibration_ID0.xlsx';
                case 1, fileName = 'calibration_ID1.xlsx';
                case 2, fileName = 'calibration_ID2.xlsx';
                otherwise
                    isValidType = false;
                    uialert(app.NewProbeUIFigure, 'Выберите корректный тип датчика!', 'Предупреждение');
            end

            if isValidType
                fullFileName = fullfile(pwd, fileName);

                if exist(fullFileName, 'file') == 2
                    try
                        m = readmatrix(fullFileName);
                        H = m(:,1); N = m(:,2);
                        H(isnan(H)) = []; N(isnan(N)) = [];

                        % Сохраняем БАЗОВУЮ ТИПОВУЮ ТАБЛИЦУ во временную структуру
                        app.TempData = struct();
                        app.TempData.LUT_data = num2cell(H);
                        app.TempData.LUT_data(:, 2) = num2cell(N);
                        app.TempData.LUT_data(:, 3) = num2cell(zeros(length(H), 1));

                        % Сохраняем БАЗОВЫЕ НАСТРОЙКИ
                        app.TempData.Settings.ProbeType = m(1, 6);
                        app.TempData.Settings.Amplitude = m(2, 6);
                        app.TempData.Settings.Periods   = m(3, 6);
                        app.TempData.Settings.Sharpness = m(4, 6);

                        app.TempData.Settings.AirSTD =    m(7, 6);
                        app.TempData.Settings.AirDelta =  m(8, 6);

                        app.TempData.Settings.WaveForm =  m(10, 6);

                        % Активируем ПЕРВУЮ кнопку для обновления Zero
                        app.ZeroButton.Enable = 'on';

                    catch ME
                        uialert(app.NewProbeUIFigure, ['Ошибка разбора файла: ' ME.message], 'Ошибка');
                    end
                else
                    uialert(app.NewProbeUIFigure, ['Файл ' fileName ' не найден!'], 'Ошибка');

                end
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % ПРЯМОЙ И НАДЕЖНЫЙ ПЕРЕНОС ДАННЫХ В ГЛАВНОЕ ОКНО
            app.MainApp.LUT_data = app.TempData.LUT_data;
            app.MainApp.UITable.Data = app.TempData.LUT_data;

            app.MainApp.Settings.ProbeType = app.TempData.Settings.ProbeType;
            app.MainApp.Settings.Amplitude = app.TempData.Settings.Amplitude;
            app.MainApp.Settings.Periods   = app.TempData.Settings.Periods;
            app.MainApp.Settings.Sharpness = app.TempData.Settings.Sharpness;
            app.MainApp.Settings.AirSTD = app.TempData.Settings.AirSTD;
            app.MainApp.Settings.AirDelta = app.TempData.Settings.AirDelta;
            app.MainApp.Settings.MeasLimitH = app.MeasLimitHEditField.Value;
            app.MainApp.Settings.WaveForm = app.TempData.Settings.WaveForm;

            % Закрываем окно калибровки, так как данные успешно сохранены
            delete(app);

        end

        % Close request function: NewProbeUIFigure
        function NewProbeUIFigureCloseRequest(app, event)

            if ~isempty(app.OriginalKzero) && ~isempty(app.OriginalAirGrad)

                % ОТКАТ ИЗМЕНЕНИЙ:
                % принудительно возвращаем старые настройки
                app.MainApp.Settings.Kzero   = app.OriginalKzero;
                app.MainApp.Settings.AirGrad = app.OriginalAirGrad;
                app.MainApp.Settings.ProbeType = app.OriginalProbeType;
                app.MainApp.Settings.AirSTD = app.OriginalAirSTD;
                app.MainApp.Settings.AirDelta = app.OriginalAirDelta;
                app.MainApp.Settings.MeasLimitH = app.OriginalMeasLimitH;
                app.MainApp.Settings.WaveForm = app.OriginalWaveForm;
            end

            % Удаляем (закрываем) текущее окно калибровки
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewProbeUIFigure = uifigure('Visible', 'off');
            app.NewProbeUIFigure.Name = 'New probe';
            app.NewProbeUIFigure.CloseRequestFcn = createCallbackFcn(app, @NewProbeUIFigureCloseRequest, true);
            app.ZeroButton = uibutton(app.NewProbeUIFigure, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Enable = 'off';
            app.ZeroButton.Text = 'Zero';
            app.AirButton = uibutton(app.NewProbeUIFigure, 'push');
            app.AirButton.ButtonPushedFcn = createCallbackFcn(app, @AirButtonPushed, true);
            app.AirButton.Enable = 'off';
            app.AirButton.Text = 'Air';
            app.TypeDropDownLabel = uilabel(app.NewProbeUIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.NewProbeUIFigure);
            app.TypeDropDown.Items = {'Тип ИД', 'ИД0', 'ИД1', 'ИД2', 'ИД3', 'ИД4', 'ИД5'};
            app.TypeDropDown.ItemsData = [-1 0 1 2 3 4 5];
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = -1;
            app.OkButton = uibutton(app.NewProbeUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Enable = 'off';
            app.OkButton.Text = 'ОК';
            app.MeasLimitmLabel = uilabel(app.NewProbeUIFigure);
            app.MeasLimitmLabel.Text = 'Meas Limit, µm';
            app.MeasLimitHEditField = uieditfield(app.NewProbeUIFigure, 'numeric');
            app.MeasLimitHEditField.Limits = [1 10000];
            app.MeasLimitHEditField.ValueDisplayFormat = '%.0f';
            app.MeasLimitHEditField.Value = 3130;
            % Show the figure after all components are created
            app.NewProbeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewProbe(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NewProbeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.NewProbeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewProbeUIFigure)
        end
    end
end