classdef sldrt_apptest_2025b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProbeMenu                   matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        ProbeTypeLabel              matlab.ui.control.Label
        SoundVolumeDropDown         matlab.ui.control.DropDown
        SoundVolumeDropDownLabel    matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        WaveFormTab                 matlab.ui.container.Tab
        WaveFormSwitch              matlab.ui.control.Switch
        SharpnessEditField          matlab.ui.control.NumericEditField
        SharpnessEditFieldLabel     matlab.ui.control.Label
        PacketNumEditField          matlab.ui.control.NumericEditField
        PacketNumEditFieldLabel     matlab.ui.control.Label
        PeriodsEditField            matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        LogicTab                    matlab.ui.container.Tab
        AirSTDEditField             matlab.ui.control.NumericEditField
        AirSTDEditFieldLabel        matlab.ui.control.Label
        AirDeltaEditField           matlab.ui.control.NumericEditField
        AirDeltaEditFieldLabel      matlab.ui.control.Label
        GradTab                     matlab.ui.container.Tab
        AirUpdateButton             matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        Res_umEditField             matlab.ui.control.NumericEditField
        ResmEditFieldLabel          matlab.ui.control.Label
        AirGradEditField            matlab.ui.control.NumericEditField
        AirgradEditFieldLabel       matlab.ui.control.Label
        CapturedCorrEditField       matlab.ui.control.NumericEditField
        CapturedCorrEditFieldLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        MeanEditField               matlab.ui.control.NumericEditField
        MeanEditFieldLabel          matlab.ui.control.Label
        AirButton                   matlab.ui.control.Button
        ZeroButton                  matlab.ui.control.Button
        UI_LUT_Axes                 matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
        GradTableContextMenu        matlab.ui.container.ContextMenu
        ClearMenu                   matlab.ui.container.Menu
        AddMenu                     matlab.ui.container.Menu
        DeleteMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        UDPClient % Здесь будет храниться сетевой объект для отправки команд

        SLDRT_App = 'sldrt_newtest_2025b'
        DataListener

        hSignalLine
        hEnvelopeLine
        hLUT_Line

        Signal
        Code
        ObjCapt
        ObjCapt_mm
        ObjCaptFlag = false
        ProbeState
        UpdateAnimationValidCnt = 0
        UIGradTableFocus = false
        UIGradTableCurrIdx = 1

        LastSentSettings
    end

    properties (Access = public)
        LUT_data % тень градуировочной таблицы
        Settings % Все настройки (состояния кнопок, значения полей ввода, данные LUT и т.д. для отправки в модель)
    end


    methods (Access = private)
        function ManualUpdateCallback(app, src, event)
            if isvalid(app)

                app.UpdateAnimationValidCnt = app.UpdateAnimationValidCnt +1;

                % если изменились настройки, передадим их в модель
                if ~isequal(app.Settings, app.LastSentSettings) && app.UpdateAnimationValidCnt > 12
                    packetData = app.Settings.toArray();
                    %write(app.UDPClient, packetData, "double", "127.0.0.1", 25010);
                    FromApp_UDP_Send(packetData);
                    app.LastSentSettings = app.Settings;
                    return;
                end
                
                data = src.OutputPort(1).Data; % данные из модели

                app.Signal = data.s;
                app.Code =  data.Nmean;
                app.Settings.AirGrad = data.N_GradAir;
                app.Settings.Kzero = data.Kzero;
                ObjCaptF = boolean( data.ObjCaptFlag);
                app.ObjCapt = data.ObjCaptN_Corr;
                app.ProbeState = data.ChartState;
                app.ObjCapt_mm = data.Hcapt;

                % рисуем сигнал с АЦП, только если находимся во вкладке WaveForm
                if isequal( app.TabGroup.SelectedTab.Title, 'WaveForm')
                    %s = double( app.Signal);
                    %sm = mean(s);
                    %s = s - sm;
                    %e = abs( hilbert(s));
                    %e = e + sm;

                    set( app.hSignalLine, 'YData', app.Signal);
                    %set( app.hEnvelopeLine, 'YData', e(1:5000));
                    drawnow limitrate;
                end

                % выводим среднее N и AirGrad
                app.AirGradEditField.Value = app.Settings.AirGrad;
                app.MeanEditField.Value = app.Code; % * K;

                o = app.UIFigure.CurrentObject;
                if ~isequal( o.Tag, 'UIGradTable')
                    app.UIGradTableFocus = false;
                end

                if ObjCaptF ~= app.ObjCaptFlag % попали в ObjectUpdate (захват)

                    % выводим захваченный код и значение толщины
                    app.CapturedCorrEditField.Value = app.ObjCapt;
                    app.Res_umEditField.Value = app.ObjCapt_mm;
                    app.ObjCaptFlag = ObjCaptF; % обновляем флаг захвата

                    % если фокус на град. таблице
                    if app.UIGradTableFocus
                        n = cell2mat( app.LUT_data( app.UIGradTableCurrIdx, 3));
                        n = n +1;
                        app.LUT_data( app.UIGradTableCurrIdx, 3) = {n};

                        m = cell2mat( app.LUT_data( app.UIGradTableCurrIdx, 2));
                        m = ( m * ( n-1) + app.ObjCapt) /n;

                        app.LUT_data( app.UIGradTableCurrIdx, 2) = {m};
                        app.UITable.Data = app.LUT_data;

                        app.PlotLUT();

                        app.UpdateLUTxxyy(); % обновим LUT xx и yy
                    end
                end

                switch app.ProbeState
                    case 6 % AirTimeout
                        app.Lamp.Color = 'Red';
                    case 2 % AirUpdate
                        app.Lamp.Color = 'Green';
                    otherwise
                        app.Lamp.Color = 'White';
                end

                app.PacketNumEditField.Value = app.UpdateAnimationValidCnt;

            end

        end


        function SetupAnimationAxes(app)
            x = ( (0:4999)/5000*20).';
            %x = (0:4999)';
            app.hSignalLine = plot( app.UIAxes, x, ones(5000, 1)*2000);
            hold( app.UIAxes, 'on');
            %app.hEnvelopeLine = plot( app.UIAxes, (0:4999).', ones(5000, 1)*2000);
            plot( app.UIAxes, [0 20], [2048 2048]);
            %plot( app.UIAxes, [0 4999], [2048 2048]);
            hold( app.UIAxes, 'off');
            ylim( app.UIAxes, [ -200 4200]);
            xlim( app.UIAxes, [ 0 20]);
            %xlim( app.UIAxes, [ 0 5000]);

            app.hLUT_Line = plot( app.UI_LUT_Axes, cell2mat( app.LUT_data(:, 1)), cell2mat( app.LUT_data(:, 2)), '-bo');
        end

        function PlotLUT(app)
            x = cell2mat( app.LUT_data(:, 1)); % толщина
            y = cell2mat( app.LUT_data(:, 2)); % код
            app.hLUT_Line.XData = x; % обновим график LUT
            app.hLUT_Line.YData = y;
        end
        
        function SyncUIFromSettings(app)
            app.UITable.Data = app.LUT_data;
            app.PlotLUT();
            
            app.AmplitudeEditField.Value = app.Settings.Amplitude;
            app.SharpnessEditField.Value = app.Settings.Sharpness;
            app.PeriodsEditField.Value = app.Settings.Periods;
            app.AirGradEditField.Value = app.Settings.AirGrad;
            app.SoundVolumeDropDown.Value = app.Settings.SoundVolume;
            app.ProbeTypeLabel.Text = app.Settings.getProbeTypeString();
            app.AirDeltaEditField.Value = app.Settings.AirDelta;
            app.AirSTDEditField.Value = app.Settings.AirSTD;
            app.WaveFormSwitch.Value = app.Settings.WaveForm;
        end
        
        function OnNewProbeClosed(app, ~, ~)
            SyncUIFromSettings(app);
            app.UpdateLUTxxyy();
        end
    end

    methods (Access = public)

        function UpdateLUTxxyy(app)
            yy = cell2mat( app.LUT_data(:, 1))';
            xx = cell2mat( app.LUT_data(:, 2))';

            [ LUTxx, LUTyy, st] = UpdateLUT( 25, flip(xx), flip(yy));

            if st == 0
                app.Settings.xx = LUTxx;
                app.Settings.yy = LUTyy;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Settings = FromApp();

            % Открываем UDP-порт на стороне компьютера
            app.UDPClient = udpport("LocalPort", 25015);

            H = [0; 20; 51; 103; 154; 181; 232; 335; 466; 698; 976; 1465; 1964; 2440; 3121];
            N = [60000; 57862; 55663; 53185; 51343; 50678; 49334; 47769; 46388; 44717; 43533; 42429; 41973; 41682; 41344];
            Num = [ 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0];

            app.LUT_data = table2cell( table( H, N, Num));
            app.UITable.ColumnFormat = {'bank','bank','short'};

            SyncUIFromSettings(app);
            
            app.StartButton.Enable = "off";

            app.SetupAnimationAxes();

            if ~bdIsLoaded( app.SLDRT_App)
                load_system( app.SLDRT_App);
                open_system( app.SLDRT_App);
            end

            set_param(app.SLDRT_App, 'BlockReduction', 'off');

            app.UpdateLUTxxyy();

            app.StartButton.Enable = "on";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.Settings.CmdZero = false;
            app.Settings.CmdAirGrad = false;
            app.Settings.CmdAirCurrent = false;

            app.UpdateAnimationValidCnt = 0;

            app.LastSentSettings.WaveForm = ~app.Settings.WaveForm;

            % st = app.tg.ModelStatus.State;
            % if isequal( st, 'RUNNING')
            %     stop( app.tg);
            %     app.StartButton.Text = "Start";
            % else
            %     start( app.tg);
            %     app.StartButton.Text = "Stop";
            %     app.DataListener = add_exec_event_listener( [app.SLDRT_App, '/Rate Transition'], 'PostOutputs', @app.ManualUpdateCallback);
            % end

            modelName = app.SLDRT_App;
            % 2. Опрашиваем текущий статус локальной модели через Simulation API
            currentStatus = get_param(modelName, 'SimulationStatus');

            % 3. Переключаем автомат состояний симуляции
            if isequal(currentStatus, 'running')
                % --- ФАЗА ОСТАНОВКИ ---
                % Если модель была запущена — уничтожаем старый листенер, чтобы не текли ресурсы
                if isfield(app, 'DataListener') && ~isempty(app.DataListener)
                    delete(app.DataListener);
                end
                
                % останавливаем измерительный конвейер в Симулинке
                set_param(modelName, 'SimulationCommand', 'stop');
                app.StartButton.Text = "Start";
                %app.StartButton.BackgroundColor = [0.94, 0.94, 0.94]; % Обычный серый
                
            else
                % --- ФАЗА ЗАПУСКА ---
                set_param(modelName, 'SimulationCommand', 'start');
                app.StartButton.Text = "Stop";
                %app.StartButton.BackgroundColor = [1.0, 0.3, 0.3]; % Витринный красный стоп-сигнал
                
                app.DataListener = add_exec_event_listener(...
                    [modelName, '/Rate Transition'], 'PostOutputs', @app.ManualUpdateCallback);
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % 1. Безопасно закрываем и освобождаем UDP-порт
            if ~isempty(app.UDPClient) && isvalid(app.UDPClient)
                clear app.UDPClient; % В MATLAB эта команда корректно закрывает системный сокет
            end

            % Удаляем слушатель данных
            if isfield(app, 'DataListener') && ~isempty(app.DataListener)
                delete(app.DataListener);
            end

            %disconnect( app.tg);
            delete(app)
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.Settings.Amplitude = round( app.AmplitudeEditField.Value);
        end

        % Value changed function: PeriodsEditField
        function PeriodsEditFieldValueChanged(app, event)
            app.Settings.Periods = round( app.PeriodsEditField.Value);
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            app.Settings.CmdZero = ~app.Settings.CmdZero;
        end

        % Button pushed function: AirButton
        function AirButtonPushed(app, event)
            app.Settings.CmdAirGrad = ~app.Settings.CmdAirGrad;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if isempty( indices)
                app.UIGradTableFocus = false;
            else
                app.UIGradTableCurrIdx = indices(1);
                app.UIGradTableFocus = true;
            end
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            if app.UIGradTableFocus
                app.LUT_data( app.UIGradTableCurrIdx, 2:3) = {0};
                app.UITable.Data = app.LUT_data;
            end
        end

        % Menu selected function: AddMenu
        function AddMenuSelected(app, event)
            [l, ~] = size( app.LUT_data);
            if l < 20
                T = cell2mat( app.LUT_data( l, 1));
                app.LUT_data( l +1, :) = {T, 0, 0};
                app.UITable.Data = app.LUT_data;
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            if app.UIGradTableFocus
                app.LUT_data( app.UIGradTableCurrIdx, :) = [];
                app.LUT_data = sortrows( app.LUT_data);
                app.UITable.Data = app.LUT_data;

                app.hLUT_Line.XData = cell2mat( app.LUT_data(:, 1));
                app.hLUT_Line.YData = cell2mat( app.LUT_data(:, 2));

                app.UpdateLUTxxyy();
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            app.UITable.Data = sortrows( app.UITable.Data);
            app.LUT_data = app.UITable.Data;

            app.hLUT_Line.XData = cell2mat( app.LUT_data(:, 1));
            app.hLUT_Line.YData = cell2mat( app.LUT_data(:, 2));

            app.UpdateLUTxxyy();
        end

        % Value changed function: SharpnessEditField
        function SharpnessEditFieldValueChanged(app, event)
            app.Settings.Sharpness = app.SharpnessEditField.Value;
        end

        % Button pushed function: AirUpdateButton
        function AirUpdateButtonPushed(app, event)
            app.Settings.CmdAirCurrent = ~app.Settings.CmdAirCurrent;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            childApp = NewProbe(app);

            childApp.NewProbeUIFigure.DeleteFcn = createCallbackFcn(app, @app.OnNewProbeClosed, true);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            ProbeSave( app, app.UITable.ColumnName);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            ProbeOpen(app);
            SyncUIFromSettings(app);
            app.UpdateLUTxxyy();
        end

        % Value changed function: SoundVolumeDropDown
        function SoundVolumeDropDownValueChanged(app, event)
            app.Settings.SoundVolume = app.SoundVolumeDropDown.Value;            
        end

        % Value changed function: AirDeltaEditField
        function AirDeltaEditFieldValueChanged(app, event)
            app.Settings.AirDelta = app.AirDeltaEditField.Value;            
        end

        % Value changed function: AirSTDEditField
        function AirSTDEditFieldValueChanged(app, event)
            app.Settings.AirSTD = app.AirSTDEditField.Value;            
        end

        % Value changed function: WaveFormSwitch
        function WaveFormSwitchValueChanged(app, event)
            app.Settings.WaveForm = app.WaveFormSwitch.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'K6-11';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProbeMenu = uimenu(app.UIFigure);
            app.ProbeMenu.Text = 'Probe';
            app.NewMenu = uimenu(app.ProbeMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New...';
            app.OpenMenu = uimenu(app.ProbeMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.ProbeMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save...';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveFormTab = uitab(app.TabGroup);
            app.WaveFormTab.Title = 'WaveForm';
            app.UIAxes = uiaxes(app.WaveFormTab);
            title(app.UIAxes, 'ADC')
            xlabel(app.UIAxes, 'ms')
            ylabel(app.UIAxes, 'Code')
            zlabel(app.UIAxes, 'Z')
            app.AmplitudeEditFieldLabel = uilabel(app.WaveFormTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.AmplitudeEditField.Limits = [0 4000];
            app.AmplitudeEditField.ValueDisplayFormat = '%.0f';
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 2000;
            app.PeriodsEditFieldLabel = uilabel(app.WaveFormTab);
            app.PeriodsEditFieldLabel.Text = 'Periods';
            app.PeriodsEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.PeriodsEditField.Limits = [1 50];
            app.PeriodsEditField.ValueDisplayFormat = '%.0f';
            app.PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodsEditFieldValueChanged, true);
            app.PeriodsEditField.Value = 2;
            app.PacketNumEditFieldLabel = uilabel(app.WaveFormTab);
            app.PacketNumEditFieldLabel.Text = 'PacketNum';
            app.PacketNumEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.PacketNumEditField.ValueDisplayFormat = '%.0f';
            app.PacketNumEditField.Editable = 'off';
            app.SharpnessEditFieldLabel = uilabel(app.WaveFormTab);
            app.SharpnessEditFieldLabel.Text = 'Sharpness';
            app.SharpnessEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.SharpnessEditField.Limits = [0 4];
            app.SharpnessEditField.ValueDisplayFormat = '%5.2f';
            app.SharpnessEditField.ValueChangedFcn = createCallbackFcn(app, @SharpnessEditFieldValueChanged, true);
            app.SharpnessEditField.Value = 1;
            app.WaveFormSwitch = uiswitch(app.WaveFormTab, 'slider');
            app.WaveFormSwitch.Items = {'sin', 'pulse'};
            app.WaveFormSwitch.ItemsData = [0 1];
            app.WaveFormSwitch.ValueChangedFcn = createCallbackFcn(app, @WaveFormSwitchValueChanged, true);
            app.WaveFormSwitch.Value = 1;
            app.LogicTab = uitab(app.TabGroup);
            app.LogicTab.Title = 'Logic';
            app.AirDeltaEditFieldLabel = uilabel(app.LogicTab);
            app.AirDeltaEditFieldLabel.Text = 'AirDelta';
            app.AirDeltaEditField = uieditfield(app.LogicTab, 'numeric');
            app.AirDeltaEditField.Limits = [0 500];
            app.AirDeltaEditField.ValueDisplayFormat = '%.0f';
            app.AirDeltaEditField.ValueChangedFcn = createCallbackFcn(app, @AirDeltaEditFieldValueChanged, true);
            app.AirDeltaEditField.Value = 40;
            app.AirSTDEditFieldLabel = uilabel(app.LogicTab);
            app.AirSTDEditFieldLabel.Text = 'AirSTD';
            app.AirSTDEditField = uieditfield(app.LogicTab, 'numeric');
            app.AirSTDEditField.Limits = [0 50];
            app.AirSTDEditField.ValueDisplayFormat = '%.0f';
            app.AirSTDEditField.ValueChangedFcn = createCallbackFcn(app, @AirSTDEditFieldValueChanged, true);
            app.AirSTDEditField.Value = 3;
            app.GradTab = uitab(app.TabGroup);
            app.GradTab.Title = 'Grad';
            app.UI_LUT_Axes = uiaxes(app.GradTab);
            xlabel(app.UI_LUT_Axes, 'T')
            ylabel(app.UI_LUT_Axes, 'C')
            zlabel(app.UI_LUT_Axes, 'Z')
            app.ZeroButton = uibutton(app.GradTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.AirButton = uibutton(app.GradTab, 'push');
            app.AirButton.ButtonPushedFcn = createCallbackFcn(app, @AirButtonPushed, true);
            app.AirButton.Text = 'Air';
            app.MeanEditFieldLabel = uilabel(app.GradTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.GradTab, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.0f';
            app.MeanEditField.Editable = 'off';
            app.UITable = uitable(app.GradTab);
            app.UITable.ColumnName = {'H'; 'N'; 'Num'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false];
            app.UITable.ColumnEditable = [true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Tag = 'UIGradTable';
            app.CapturedCorrEditFieldLabel = uilabel(app.GradTab);
            app.CapturedCorrEditFieldLabel.Text = 'Captured Corr';
            app.CapturedCorrEditField = uieditfield(app.GradTab, 'numeric');
            app.CapturedCorrEditField.ValueDisplayFormat = '%.0f';
            app.CapturedCorrEditField.Editable = 'off';
            app.AirgradEditFieldLabel = uilabel(app.GradTab);
            app.AirgradEditFieldLabel.Text = 'Air grad';
            app.AirGradEditField = uieditfield(app.GradTab, 'numeric');
            app.AirGradEditField.ValueDisplayFormat = '%.0f';
            app.ResmEditFieldLabel = uilabel(app.GradTab);
            app.ResmEditFieldLabel.Text = 'Res, µm';
            app.Res_umEditField = uieditfield(app.GradTab, 'numeric');
            app.Res_umEditField.ValueDisplayFormat = '%.0f';
            app.Res_umEditField.Editable = 'off';
            app.Lamp = uilamp(app.GradTab);
            app.AirUpdateButton = uibutton(app.GradTab, 'push');
            app.AirUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @AirUpdateButtonPushed, true);
            app.AirUpdateButton.Text = 'AirUpdate';
            app.SoundVolumeDropDownLabel = uilabel(app.UIFigure);
            app.SoundVolumeDropDownLabel.Text = 'Sound Volume';
            app.SoundVolumeDropDown = uidropdown(app.UIFigure);
            app.SoundVolumeDropDown.Items = {'Off', 'Mid', 'Max'};
            app.SoundVolumeDropDown.ItemsData = [0 1 2];
            app.SoundVolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundVolumeDropDownValueChanged, true);
            app.SoundVolumeDropDown.Value = 0;
            app.ProbeTypeLabel = uilabel(app.UIFigure);
            app.ProbeTypeLabel.Text = 'ИД2';
            app.GradTableContextMenu = uicontextmenu(app.UIFigure);
            app.ClearMenu = uimenu(app.GradTableContextMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.AddMenu = uimenu(app.GradTableContextMenu);
            app.AddMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMenuSelected, true);
            app.AddMenu.Text = 'Add';
            app.DeleteMenu = uimenu(app.GradTableContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.GradTableContextMenu
            app.UITable.ContextMenu = app.GradTableContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sldrt_apptest_2025b

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end